classdef Frame12_Traening_med_livefeedback < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        FeedbackLitterluftUIAxes        matlab.ui.control.UIAxes
        AfbrydFrame12Button             matlab.ui.control.Button
        PauseFrame12Button              matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        NedtaellingAntalVejrT_TextArea  matlab.ui.control.TextArea
        NedtaellingAntalVejrtraekningEditField  matlab.ui.control.NumericEditField
        infoFrame12Button               matlab.ui.control.Button
        FrdiggrButton                   matlab.ui.control.Button
        VejrtrkningButton               matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        callingApp; % callingApp
        mainMenuApp % Reference to mainMenuApp
        flows;% Data of breath
        pressures; 
        initFlow;
        initPressure;
        flowVector; %To store entire session
        amountCounter = 1;
        
        samplesBeforeFiltering = 100;%MUST BE MIN 15! Remember Nyquist.
        dataSampled;% To store the data sampled
        filteredData; 
        counter = 1; % Counts samples sampled
        detectionCounter=1; %Counts starts and ends detected. Starter fra 1 pga. array indeksering i matlab.
        firstRun = true;
        zf;
        
        RR;
        C;
        MIP;
        duration;
        threshold;
        sF;
        amount;
        modstand;
        
        %Variables for detection
        endIn=1; %First case
        startIn;
        starts;
        ends;
        %plotStepSize = 5;
        volumesIn;
        avgFlowIn;
        
        
    end
    
    methods (Access = private)
        
        function updateGraph(app,data)
            n = length(data);
            x = 1:n;
            stepsize = 50;
            
            for i=1:stepsize:n
                plot(app.FeedbackLitterluftUIAxes, x(1:i), data(1:i));
                drawnow;
            end
            
        end
        
        function updateGraph2(app,data)
            %Fix UI-axes
                %cla(app.FeedbackLitterluftUIAxes, 'reset');
                 app.FeedbackLitterluftUIAxes.YLim=([60*min(app.flows(1,:))-1 60*max(app.flows(1,:))+1]);% Tilpasser akserne så de passer med inputdata
                 app.FeedbackLitterluftUIAxes.XLim=([0 app.amountCounter*(length(app.flows(1,:))/app.sF)]); %tilpasser akserne så de passer med inputdata
%                 app.FeedbackLitterluftUIAxes.YGrid = 'on';
                 %app.FeedbackLitterluftUIAxes.YLabel.String = "Flow (L/min)";
                 %app.FeedbackLitterluftUIAxes.XLabel.String = "Tid (s)";
                 %pause(0.01);
                
            if(app.amountCounter == 1)
                multiplier = 0;
                adder = 1;
            else
                multiplier = app.amountCounter-1;
                adder = 0;
            end
            
            %To account for matlab indexing of 1, and to only look at the
            %part of the signal corresponding to the current breath.
            if(max(data(1*multiplier*app.sF*app.duration+adder:end)) > 0.75*max(app.initFlow))
                color = 'g';
            elseif(max(data) > 0.5*max(app.flowVector))
                color = 'y';
            else
                color = 'r';
            end
            
            x = linspace(0,length(data)/app.sF,length(data));
            plot(app.FeedbackLitterluftUIAxes, x, data.*60,"Color",color,"LineWidth",2);
            %legend(app.FeedbackLitterluftUIAxes, 'Vejrtrækning')
            drawnow; 

            
        end
        
        function generateBreaths(app) % Generating [amount] breaths and saves to global variables app.flows and app.pressures.
            app.flows = zeros(app.amount,app.sF*app.duration); %Each breath with flow simulation gets its own column in the matrix 
            app.pressures = zeros(app.amount,app.sF*app.duration);
            for i = 1:app.amount
                [app.flows(i,:), app.pressures(i,:)] = breathSimulation(app.RR, app.C, app.MIP,app.duration,app.threshold, app.sF);
            end
        end
        
%         function concatenateFlow(app)
%             app.flowVector = [];
%             for i=1:app.amount
%                 app.flowVector = [app.flowVector app.flows(i,:)];
%             end
%             
 %       end
        
        function lowpassFilter(app)
            Fc=12; % Cut-off frequency (from 2 Hz to 6 Hz depending to the type of your electrod)
            N=4; % Filter Order
            Wn=2*Fc/app.sF;
            [B, A] = butter(N,Wn, 'low'); %filter's parameters 
            
            if(length(app.dataSampled) >= app.samplesBeforeFiltering && app.firstRun)% We need 15 samples to run filtfilt filtering.            
                app.firstRun = false;
                [filtereddata, app.zf] = filter(B,A,app.dataSampled);
                app.filteredData = [app.filteredData filtereddata];
            elseif(length(app.dataSampled) >= app.samplesBeforeFiltering)
                [filtereddata, app.zf] = filter(B,A,app.dataSampled,app.zf);
                app.filteredData = [app.filteredData filtereddata];
            end
%             if(length(app.dataSampled) >= app.samplesBeforeFiltering)% We need 15 samples to run filtfilt filtering.
%                     app.filteredData = [app.filteredData filtfilt(B,A,app.dataSampled)];
%             end
            
        end
        
        function parameterDetection(app) %Functino to determine starts and ends of inspirations
            
           if app.endIn == 1 % To account for the first scenario where endIn=1 to NOT get offset of 1
               if(max(app.filteredData)==0)
                   return;
               end
                tempstart = find(app.filteredData(app.endIn:end) > 0, 1);
                if(~isempty(tempstart))
                    app.startIn = tempstart;
                    app.starts(app.detectionCounter)=tempstart;
                end
           else
                app.startIn = find(app.filteredData(app.endIn:end) > 0, 1) + app.endIn;
                tempstart = app.startIn;
                if(app.startIn >= (app.endIn+1*app.sF))%Threshold between end of breath to finding start.
                    app.starts(app.detectionCounter)=app.startIn;
                end
        
           end
           tempEnd=find(app.filteredData(app.startIn:end) < 0, 1) + app.startIn; % Can be improved by setting threshold between start and end with if.
           if(~isempty(tempEnd))
                app.endIn = tempEnd;
           end
        
           if(app.endIn >= (app.startIn+1*app.sF))%Threshold between start of breath to finding end.
               app.ends(app.detectionCounter)=app.endIn;
               
               app.detectionCounter = app.detectionCounter + 1; %count breaths
               app.NedtaellingAntalVejrtraekningEditField.Value = app.detectionCounter - 1; %Display +1 breath detected
           end
        end


        function sample = readSamples(app)
            loopCounter = 1;
            while(app.counter <= length(app.flowVector) ...
                  && loopCounter <= app.samplesBeforeFiltering) %sampling stepsizesamples
                sample = app.flowVector(app.counter); %sampling - replace here with sensor
                app.dataSampled(loopCounter) = sample; %Store
                app.counter = app.counter + 1;
                
                loopCounter = loopCounter + 1;
                
            end
        end
        
        function runSampling(app)%Sampling one breath
            %Will be replaced by sampling in real program
                while(app.counter <= length(app.flowVector))
                    
                    sample = readSamples(app); %Read samples and add to counter
                                                  
                    if(app.counter >= app.samplesBeforeFiltering) %Necessary because 12 values are needed to filter..            
                        lowpassFilter(app); %Filtering pressure and flow
                        parameterDetection(app);
                        
                        %Only plots every 5*rotations of sampling to save computations
                        if(mod(app.counter,app.samplesBeforeFiltering)==1) %== 1 since we start counter on 1.
                            updateGraph2(app,app.filteredData);
                        end
                    end
                  
                end
                %%%plotStartsandEnds(app); 
            %Will be replaced by sampling in real program
        end
        
        function plotStartsandEnds(app)
            hold(app.FeedbackLitterluftUIAxes,'on');
            
            plot(app.FeedbackLitterluftUIAxes, app.starts./app.sF, app.filteredData(app.starts),'*');
            plot(app.FeedbackLitterluftUIAxes, app.ends./app.sF, app.filteredData(app.ends),'*');
            legend(app.FeedbackLitterluftUIAxes, 'Vejrtrækning', 'Start','Slut')
            drawnow;
            
            hold(app.FeedbackLitterluftUIAxes,'off');
        end
        
        function saveData(app)
            
            grandAvgFlow = mean(app.avgFlowIn);
            grandAvgVol = mean(app.volumesIn);
            
            filePath = mfilename('fullpath');
            filePath = filePath(1:end-33);%To save to relative path
            cd(filePath);
            fileName = "dataHistory.txt";
            fileID = fopen(fileName,'a');
            
            fprintf(fileID,'%d %d %d\n', app.MIP, grandAvgFlow, grandAvgVol);
            fclose(fileID);

            
        end
        
        function calculateVolumeandAvgFlow(app)
            
            for i=1:app.detectionCounter-1
                app.volumesIn(i) = trapz(app.filteredData(app.starts(i):app.ends(i)).*(1/app.sF))
                app.avgFlowIn(i) = mean(app.filteredData(app.starts(i):app.ends(i)))
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, mainMenuApp, initFlow, initPressure, RR, C, MIP, duration, threshold, sF, amount, modstand)
            app.callingApp = callingApp;
            app.mainMenuApp = mainMenuApp;
            
            app.initFlow = initFlow;
            app.initPressure=initPressure;
                       
            app.RR = RR;
            app.C = C;
            app.MIP = MIP;
            app.duration=duration;
            app.threshold=threshold;
            app.sF=sF;
            app.amount = amount;
            app.modstand = modstand;
            app.filteredData = zeros(1, app.samplesBeforeFiltering); %Filtered sample data initialization
            
            %Will be replaced by sampling in real program
                generateBreaths(app); % Generate all breaths, instead of sampling
            %Will be replaced by sampling in real program    


        end

        % Button pushed function: FrdiggrButton
        function FrdiggrButtonPushed(app, event)
            calculateVolumeandAvgFlow(app);
            saveData(app);
            
            delete(app.callingApp);
            Frame14_afsluttendeFeedback(app, app.mainMenuApp, app.initFlow,app.initPressure, app.RR,app.C,app.MIP,app.duration,app.threshold, app.sF, app.amount, app.modstand);
        end

        % Button pushed function: AfbrydFrame12Button
        function AfbrydFrame12ButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure,'Er du sikker på du vil afbryde din træning?','Afbryd træning',...
                        'Icon','warning');
            if selection == "OK"
                delete(app);
            end
            
        end

        % Button pushed function: VejrtrkningButton
        function VejrtrkningButtonPushed(app, event)
            %To show one breath at the time.
            app.FeedbackLitterluftUIAxes.YLim=([min(app.flows(1,:))-1 max(app.flows(1,:))+1]);% Tilpasser akserne så de passer med inputdata
            app.FeedbackLitterluftUIAxes.XLim=([0 app.amountCounter*(length(app.flows(1,:))/app.sF)]); %tilpasser akserne så de passer med inputdata
            
            if(app.amountCounter <= app.amount)
               
                app.flowVector = [app.flowVector app.flows(app.amountCounter,:)];
                runSampling(app); %Sample one breath
                app.amountCounter = app.amountCounter + 1;
            end
            
            
            app.FrdiggrButton.Enable = 'on';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {466, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FeedbackLitterluftUIAxes = uiaxes(app.LeftPanel);
            title(app.FeedbackLitterluftUIAxes, 'Feedbackgraf')
            xlabel(app.FeedbackLitterluftUIAxes, 'Volumen (L)')
            ylabel(app.FeedbackLitterluftUIAxes, 'Flow (L/s)')
            app.FeedbackLitterluftUIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.AfbrydFrame12Button = uibutton(app.LeftPanel, 'push');
            app.AfbrydFrame12Button.ButtonPushedFcn = createCallbackFcn(app, @AfbrydFrame12ButtonPushed, true);
            app.AfbrydFrame12Button.Text = 'Afbryd';
            app.PauseFrame12Button = uibutton(app.LeftPanel, 'push');
            app.PauseFrame12Button.Enable = 'off';
            app.PauseFrame12Button.Text = 'Pause';
            app.RightPanel = uipanel(app.GridLayout);
            app.NedtaellingAntalVejrT_TextArea = uitextarea(app.RightPanel);
            app.NedtaellingAntalVejrT_TextArea.Value = {'Vejrtrækninger registreret'};
            app.NedtaellingAntalVejrtraekningEditField = uieditfield(app.RightPanel, 'numeric');
            app.infoFrame12Button = uibutton(app.RightPanel, 'push');
            app.infoFrame12Button.Tooltip = {'Din træning er nu igang.'; 'Du skal foretage en hurtig og dyb indånding efterfulgt af en fuld udånding. Boksen til højre '; 'fortæller dig hvor mange gange du skal gentage '; 'dette pr. sæt.'; 'Grafen viser hvor hurtig og dyb din indånding '; 'Grøn = Super! Gul = Giv det sidste!'; 'Rød = Giv den et forsøg mere'};
            app.infoFrame12Button.Text = 'Info';
            app.FrdiggrButton = uibutton(app.RightPanel, 'push');
            app.FrdiggrButton.ButtonPushedFcn = createCallbackFcn(app, @FrdiggrButtonPushed, true);
            app.FrdiggrButton.Enable = 'off';
            app.FrdiggrButton.Text = 'Færdiggør';
            app.VejrtrkningButton = uibutton(app.RightPanel, 'push');
            app.VejrtrkningButton.ButtonPushedFcn = createCallbackFcn(app, @VejrtrkningButtonPushed, true);
            app.VejrtrkningButton.Text = 'Vejrtrækning';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Frame12_Traening_med_livefeedback(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end