classdef Frame14_afsluttendeFeedback < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FlotDagenstrningergennemfrtLabel  matlab.ui.control.Label
        HerkandusehvordandintrningergetdesidstesessionerLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        AfsluttrningButton           matlab.ui.control.Button
        infoFrame12Button            matlab.ui.control.Button
        AntaldagesTraeningEditField  matlab.ui.control.NumericEditField
        ParametreButtonGroup         matlab.ui.container.ButtonGroup
        MuskelstyrkeButton           matlab.ui.control.RadioButton
        DybdeButton                  matlab.ui.control.RadioButton
        HastighedButton              matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        callingApp;
        mainMenuApp; %Reference to main menu app
        initFlow;
        initPressure;
        
        RR;
        C;
        MIP;
        duration;
        threshold;
        sF;
        amount; 
        modstand;
        
        dataHistory; %Variable for storing loaded historic data
        MIPHistory;
        flowHistory;
        volHistory;
        lag;
        
        sessionsToDisplay;
    end
    
    methods (Access = private)
        
        function loadHistory(app)
            
            filePath = mfilename('fullpath');
            filePath = filePath(1:end-27);%To save to relative path
            cd(filePath);%To ensure that we are in the correct location
            fileName = "dataHistory.txt";
            delimiterIn = ' ';
            %headerlinesIn = 1;
            app.dataHistory = importdata(fileName,delimiterIn);
            temp = app.dataHistory;
            app.MIPHistory = app.dataHistory(:,1);
            app.flowHistory = app.dataHistory(:,2);
            app.volHistory = app.dataHistory(:,3);
            
        end
        
        function calculateDevelopment(app)
            app.sessionsToDisplay = size(app.dataHistory,1);
            app.AntaldagesTraeningEditField.Value = app.sessionsToDisplay;
            
            if(app.sessionsToDisplay < 5)
                app.lag = 1;
            
            else
                app.lag = 5;
            end


        end
        
        function displayDevelopment(app, data)
            dataMovAvg = movmean(data,app.lag);
            x = 1:app.sessionsToDisplay;
            
            app.UIAxes.XLim=([1 app.sessionsToDisplay + 1]); %+1 in case of only 1 session
            plot(app.UIAxes,x,dataMovAvg)
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, mainMenuApp, initFlow, initPressure, RR, C, MIP, duration, threshold, sF, amount, modstand)
            delete(callingApp); % Lukker calling window 
            app.mainMenuApp = mainMenuApp;
            app.initFlow = initFlow;
            app.initPressure=initPressure;
            
            app.RR = RR;
            app.C = C;
            app.MIP = MIP;
            app.duration=duration;
            app.threshold=threshold;
            app.sF=sF;
            app.amount = amount;
            app.modstand = modstand;
            
            loadHistory(app);
            calculateDevelopment(app);
            displayDevelopment(app,app.MIPHistory)
            
            
        end

        % Selection changed function: ParametreButtonGroup
        function ParametreButtonGroupSelectionChanged(app, event)
            selectedButton = app.ParametreButtonGroup.SelectedObject;
            if(selectedButton == app.DybdeButton)
                app.UIAxes.YLabel.String = "Dybde (L)";
                displayDevelopment(app,app.volHistory)
            elseif(selectedButton == app.HastighedButton)
                app.UIAxes.YLabel.String = "Hastighed (L/s)";
               displayDevelopment(app,app.flowHistory)
            else
                app.UIAxes.YLabel.String = "Muskelstyrke";
                displayDevelopment(app,app.MIPHistory)
            end
        end

        % Button pushed function: AfsluttrningButton
        function AfsluttrningButtonPushed(app, event)
            trainingCompleted(app.mainMenuApp, app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FlotDagenstrningergennemfrtLabel = uilabel(app.UIFigure);
            app.FlotDagenstrningergennemfrtLabel.Text = 'Flot! Dagens træning er gennemført.';
            app.HerkandusehvordandintrningergetdesidstesessionerLabel = uilabel(app.UIFigure);
            app.HerkandusehvordandintrningergetdesidstesessionerLabel.Text = 'Her kan du se hvordan din træning er gået de sidste         sessioner';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Sessioner')
            ylabel(app.UIAxes, 'Muskelstyrke')
            app.AfsluttrningButton = uibutton(app.UIFigure, 'push');
            app.AfsluttrningButton.ButtonPushedFcn = createCallbackFcn(app, @AfsluttrningButtonPushed, true);
            app.AfsluttrningButton.Text = 'Afslut træning';
            app.infoFrame12Button = uibutton(app.UIFigure, 'push');
            app.infoFrame12Button.Tooltip = {'Du har nu gennemført dagens træning.'; 'Grafen viser udviklingen for din muskelstyrke, din vejtrækningsdybde, og din vejtrækningshastighed over de gemte træningssessioner.'; 'Trykker du på “Afslut træning” kommer du tilbage til hovedmenuen.'};
            app.infoFrame12Button.Text = 'Info';
            app.AntaldagesTraeningEditField = uieditfield(app.UIFigure, 'numeric');
            app.ParametreButtonGroup = uibuttongroup(app.UIFigure);
            app.ParametreButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ParametreButtonGroupSelectionChanged, true);
            app.ParametreButtonGroup.Title = 'Parametre';
            app.MuskelstyrkeButton = uiradiobutton(app.ParametreButtonGroup);
            app.MuskelstyrkeButton.Text = 'Muskelstyrke';
            app.MuskelstyrkeButton.Value = true;
            app.DybdeButton = uiradiobutton(app.ParametreButtonGroup);
            app.DybdeButton.Text = 'Dybde';
            app.HastighedButton = uiradiobutton(app.ParametreButtonGroup);
            app.HastighedButton.Text = 'Hastighed';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Frame14_afsluttendeFeedback(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end