classdef Frame1_2_indstillinger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        VgtkgEditFieldLabel            matlab.ui.control.Label
        VgtkgEditField                 matlab.ui.control.NumericEditField
        NavnEditFieldLabel             matlab.ui.control.Label
        NavnEditField                  matlab.ui.control.EditField
        HjdecmEditFieldLabel           matlab.ui.control.Label
        HjdecmEditField                matlab.ui.control.NumericEditField
        AlderrEditFieldLabel           matlab.ui.control.Label
        AlderrEditField                matlab.ui.control.NumericEditField
        Switch                         matlab.ui.control.Switch
        KnLabel                        matlab.ui.control.Label
        TrningsindstillingerLabel      matlab.ui.control.Label
        AntalrepetitionerEditFieldLabel  matlab.ui.control.Label
        AntalrepetitionerEditField     matlab.ui.control.NumericEditField
        AntalstEditFieldLabel          matlab.ui.control.Label
        AntalstEditField               matlab.ui.control.NumericEditField
        TrningsforlbvarighedEditFieldLabel  matlab.ui.control.Label
        TrningsforlbvarighedEditField  matlab.ui.control.NumericEditField
        AfbrydButton                   matlab.ui.control.Button
        GodkendButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp % Description
        nameChanged % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, name, age, weight, height, repetitions, sets, weeks)
            app.mainApp = callingApp;
            
            app.NavnEditField.Value = name;
            app.AlderrEditField.Value = age;
            app.VgtkgEditField.Value = weight;
            app.HjdecmEditField.Value = height;
            app.AntalrepetitionerEditField.Value = repetitions;
            app.AntalstEditField.Value = sets;
            app.TrningsforlbvarighedEditField.Value = weeks;
            
        end

        % Button pushed function: AfbrydButton
        function AfbrydButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: GodkendButton
        function GodkendButtonPushed(app, event)
            updateSettingsforPerson(app.mainApp, app, app.NavnEditField.Value, app.AlderrEditField.Value, ...
                app.VgtkgEditField.Value, app.HjdecmEditField.Value, app.AntalrepetitionerEditField.Value, ...
                app.AntalstEditField.Value, app.TrningsforlbvarighedEditField.Value,app.Switch.Value)
        end

        % Value changed function: NavnEditField
        function NavnEditFieldValueChanged(app, event)
            value = app.NavnEditField.Value;
            if ~(value == "Navn")
                app.nameChanged = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.VgtkgEditFieldLabel = uilabel(app.GridLayout);
            app.VgtkgEditFieldLabel.Text = 'Vægt(kg)';
            app.VgtkgEditField = uieditfield(app.GridLayout, 'numeric');
            app.VgtkgEditField.Limits = [0 500];
            app.NavnEditFieldLabel = uilabel(app.GridLayout);
            app.NavnEditFieldLabel.Text = 'Navn';
            app.NavnEditField = uieditfield(app.GridLayout, 'text');
            app.NavnEditField.ValueChangedFcn = createCallbackFcn(app, @NavnEditFieldValueChanged, true);
            app.NavnEditField.Value = 'Navn';
            app.HjdecmEditFieldLabel = uilabel(app.GridLayout);
            app.HjdecmEditFieldLabel.Text = 'Højde(cm)';
            app.HjdecmEditField = uieditfield(app.GridLayout, 'numeric');
            app.HjdecmEditField.Limits = [0 250];
            app.AlderrEditFieldLabel = uilabel(app.GridLayout);
            app.AlderrEditFieldLabel.Text = 'Alder(år)';
            app.AlderrEditField = uieditfield(app.GridLayout, 'numeric');
            app.AlderrEditField.Limits = [0 110];
            app.Switch = uiswitch(app.GridLayout, 'slider');
            app.Switch.Items = {'Mand', 'Kvinde'};
            app.Switch.Value = 'Mand';
            app.KnLabel = uilabel(app.GridLayout);
            app.KnLabel.Text = 'Køn';
            app.TrningsindstillingerLabel = uilabel(app.GridLayout);
            app.TrningsindstillingerLabel.Text = 'Træningsindstillinger';
            app.AntalrepetitionerEditFieldLabel = uilabel(app.GridLayout);
            app.AntalrepetitionerEditFieldLabel.Text = 'Antal repetitioner';
            app.AntalrepetitionerEditField = uieditfield(app.GridLayout, 'numeric');
            app.AntalrepetitionerEditField.Limits = [0 50];
            app.AntalstEditFieldLabel = uilabel(app.GridLayout);
            app.AntalstEditFieldLabel.Text = 'Antal sæt';
            app.AntalstEditField = uieditfield(app.GridLayout, 'numeric');
            app.AntalstEditField.Limits = [0 10];
            app.TrningsforlbvarighedEditFieldLabel = uilabel(app.GridLayout);
            app.TrningsforlbvarighedEditFieldLabel.Text = 'Træningsforløbvarighed';
            app.TrningsforlbvarighedEditField = uieditfield(app.GridLayout, 'numeric');
            app.TrningsforlbvarighedEditField.Limits = [0 Inf];
            app.AfbrydButton = uibutton(app.GridLayout, 'push');
            app.AfbrydButton.ButtonPushedFcn = createCallbackFcn(app, @AfbrydButtonPushed, true);
            app.AfbrydButton.Text = 'Afbryd';
            app.GodkendButton = uibutton(app.GridLayout, 'push');
            app.GodkendButton.ButtonPushedFcn = createCallbackFcn(app, @GodkendButtonPushed, true);
            app.GodkendButton.Text = 'Godkend';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Frame1_2_indstillinger(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end