classdef Frame1_Hovedmenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        AntalOpnaaedeTraeningerEditField  matlab.ui.control.NumericEditField
        OpnaaedeTraeningerLabel   matlab.ui.control.Label
        VelkommenLabel            matlab.ui.control.Label
        VelkommenOgNavnTextArea   matlab.ui.control.TextArea
        RightPanel                matlab.ui.container.Panel
        StartKnapHovedmenuButton  matlab.ui.control.Button
        TraeningshistorikButton   matlab.ui.control.Button
        KnapHistorikImage         matlab.ui.control.Image
        TrofaerButton             matlab.ui.control.Button
        KnapTrofaerImage2         matlab.ui.control.Image
        IndstillingerButton       matlab.ui.control.Button
        KnapIndstillingerImage3   matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        nextWindow % window for analyzing dayShape
        
        RR;
        C;
        MIP;
        duration;
        threshold;
        sF;
        amount;
        
        MIPhistory;  %In case the user chose not to test daily shape, the latest stored MIP is used
        
        %Settings for the person
        personName="Indtast navn under indstillinger"; 
        personAge=0;
        personWeight=0;
        personHeight=0;
        personGender = "Mand";
        personRepetitions = 10;%Standard reps
        personSets = 3; %Standard sets
        personWeeks = 8;%Standard program
    end
    
    methods (Access = private)
        
        function loadSessions(app)
            fileName = "dataHistory.txt";
            delimiterIn = ' ';
            %headerlinesIn = 1;
            dataHistory = importdata(fileName,delimiterIn);
            if(~isempty(dataHistory))
                app.MIPhistory = dataHistory(end,1);
            end
            
            app.AntalOpnaaedeTraeningerEditField.Value = size(dataHistory,1);
        end
        
        
    end
    
    methods (Access = public)
        
        function trainingCompleted(app, callingApp)
            delete(callingApp);
            
            loadSessions(app);
        end
        
        function updateSettingsforPerson(app, callingApp, name, age, weight, height, repetitions, sets, weeks, gender)
            app.personName = name;
            app.personAge = age;
            app.personWeight = weight;
            app.personHeight = height;
            app.personRepetitions = repetitions;
            app.personSets = sets;
            app.personWeeks = weeks;
            app.personGender = gender;
            
            app.VelkommenOgNavnTextArea.Value = name;
            
            delete(callingApp);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, RR, C, MIP, duration, threshold, sF, amount)
%             app.BreathData = load("breath_sim.mat");
%             app.BreathData = app.BreathData.breath;
            delete(callingApp);
            
            app.VelkommenOgNavnTextArea.Value = app.personName; %TODO save name and other values to textFile

              
            app.RR = RR;
            app.C = C;
            app.MIP = MIP;
            app.duration=duration;
            app.threshold=threshold;
            app.sF=sF;
            app.amount = amount;
            
            loadSessions(app);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {426, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: StartKnapHovedmenuButton
        function StartKnapHovedmenuButtonPushed(app, event)

            try
                app.nextWindow = Frame2_2vejrtraekninger(app, app.RR,app.C,app.MIP,app.duration,app.threshold, app.sF, app.amount, app.MIPhistory, app.personRepetitions, app.personSets, app.personWeeks);    
            catch 
                msgbox("En fejl opstod ved åbning af dagsforms test vindue","Fejl - Hovedmenu")
            end
            
                                                   
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: IndstillingerButton
        function IndstillingerButtonPushed(app, event)
            Frame1_2_indstillinger(app, app.personName, app.personAge, app.personWeight, app.personHeight, app.personRepetitions, app.personSets, app.personWeeks);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AntalOpnaaedeTraeningerEditField = uieditfield(app.LeftPanel, 'numeric');
            app.OpnaaedeTraeningerLabel = uilabel(app.LeftPanel);
            app.OpnaaedeTraeningerLabel.Text = 'Træningssessioner opnået';
            app.VelkommenLabel = uilabel(app.LeftPanel);
            app.VelkommenLabel.Text = 'Velkommen!';
            app.VelkommenOgNavnTextArea = uitextarea(app.LeftPanel);
            app.VelkommenOgNavnTextArea.Editable = 'off';
            app.VelkommenOgNavnTextArea.Value = {'Hans Hansen'};
            app.RightPanel = uipanel(app.GridLayout);
            app.StartKnapHovedmenuButton = uibutton(app.RightPanel, 'push');
            app.StartKnapHovedmenuButton.ButtonPushedFcn = createCallbackFcn(app, @StartKnapHovedmenuButtonPushed, true);
            app.StartKnapHovedmenuButton.Text = 'Start træning';
            app.TraeningshistorikButton = uibutton(app.RightPanel, 'push');
            app.TraeningshistorikButton.Text = 'Træningshistorik';
            app.KnapHistorikImage = uiimage(app.RightPanel);
            app.KnapHistorikImage.ImageSource = 'B_Historik_billede_knap.png';
            app.TrofaerButton = uibutton(app.RightPanel, 'push');
            app.TrofaerButton.Text = 'Trofæer';
            app.KnapTrofaerImage2 = uiimage(app.RightPanel);
            app.KnapTrofaerImage2.ImageSource = 'B_Trofaer_knap.png';
            app.IndstillingerButton = uibutton(app.RightPanel, 'push');
            app.IndstillingerButton.ButtonPushedFcn = createCallbackFcn(app, @IndstillingerButtonPushed, true);
            app.IndstillingerButton.Text = 'Indstillinger';
            app.KnapIndstillingerImage3 = uiimage(app.RightPanel);
            app.KnapIndstillingerImage3.ImageSource = 'B_Indsillinger_knap.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Frame1_Hovedmenu(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end