classdef Frame2_2vejrtraekninger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Introtekst2vejrtraekningerLabel  matlab.ui.control.Label
        AfbrydButton        matlab.ui.control.Button
        Image               matlab.ui.control.Image
        infoFrame2Button    matlab.ui.control.Button
        VejrtrkningregistreretEditFieldLabel  matlab.ui.control.Label
        VejrtrkningregistreretEditField  matlab.ui.control.NumericEditField
        FlotfortstterLabel  matlab.ui.control.Label
        skipFrame12Button   matlab.ui.control.Button
        VejrtrkningButton   matlab.ui.control.Button
        Lamp                matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        initFlow % simulated flows data matrix  recieved from previous window.
        initPressure % simulated pressures data matrix  recieved from previous window. 
        callingApp % Calling app reference
        mainMenuApp % MainMenuApp reference (same as calling app)
        newWindow % new window to open: live training plot
        
        thresholdTable = 0:2:106;
        
        RR;
        C;
        MIP;
        duration;
        threshold;
        sF;
        amount;
        MIPhistory % In case the user chose not to test daily shape, the latest stored MIP is used
        personRepetitions;%Standard reps
        personSets; %Standard sets
        personWeeks;%Standard program % Description
    end


    methods (Access = private)
        
        %returns resistance setting on the IMT device
        function result = calculateThreshold(app, peak)
            currentWeek = floor(app.personWeeks/2); %Current week calculation because we do not have real program
            if(currentWeek <= 6)
                app.threshold = (peak*(0.25+0.05*currentWeek));
            
            else
                app.threshold = 0.6*peak;
            end
            
            result = find(app.thresholdTable <= app.threshold,1,'last'); %So as to always choose the lower setting
            
        end


        function lowpassfilter(app)
            Fc=12; % Cut-off frequency (from 2 Hz to 6 Hz depending to the type of your electrod)
            N=4; % Filter Order
            Wn=2*Fc/app.sF;
            [B, A] = butter(N,Wn, 'low'); %filter's parameters 
            
            app.initFlow = filtfilt(B,A,app.initFlow);
            app.initPressure = filtfilt(B,A,app.initPressure);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, RR, C, MIP, duration, threshold, sF, amount, MIPhistory, repetitions, sets, weeks)
            app.callingApp = callingApp;
            app.mainMenuApp = callingApp;
            
            
            app.RR = RR;
            app.C = C;
            app.MIP = MIP;
            app.duration=duration;
            app.threshold=threshold;
            app.sF=sF;
            app.amount = amount;
            app.MIPhistory = MIPhistory;
            
            app.personRepetitions = repetitions;
            app.personSets = sets;
            app.personWeeks = weeks;


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: AfbrydButton
        function AfbrydButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: VejrtrkningButton
        function VejrtrkningButtonPushed(app, event)
            [app.initFlow, app.initPressure] = breathSimulation(app.RR,app.C,app.MIP,app.duration,app.threshold, app.sF); %Generating initiating flow and pressure
            lowpassfilter(app);
            
            [peak loc] = findpeaks(app.initPressure); %Todo Add intelligens regarding this detection
            
            if(peak > 10)%Threshold for hvornår vejtrækningen registreres Maybe add: "&& peak > 0.5*app.MIPhistory" 
                app.VejrtrkningregistreretEditField.Value = 1; %Indikerer at der har været en vejtrækning
                app.Lamp.Enable = 'on';
                app.FlotfortstterLabel.Visible = 'on';
                
                modstand = calculateThreshold(app, peak); %Algorithm to determine IMT resistance setting
                app.MIP = peak; %Update MIP to actual, instead of the one inserted in modelling window
            
                pause(0.5);
            
                frame6_modstandsindstilling(app, app.mainMenuApp, app.initFlow, app.initPressure, modstand, app.RR,app.C,app.MIP,app.duration,app.threshold, app.sF, app.amount)
            else
                msgbox("Prøv igen, inspiration var ikke kraftig nok til at blive registreret","Prøv igen")
            end
            
            
        end

        % Button pushed function: skipFrame12Button
        function skipFrame12ButtonPushed(app, event)
            if ~isempty(app.MIPhistory)
                modstand = calculateThreshold(app, app.MIPhistory); %Algorithm to determine IMT resistance setting
                frame6_modstandsindstilling(app, app.mainMenuApp, app.initFlow, app.initPressure, modstand, app.RR,app.C,app.MIPhistory,app.duration,app.threshold, app.sF, app.amount)
            else
                msgbox("Der er ikke registreret nogen tidligere dagsform. Du bedes udføre en hurtig og hård indånding.", "Fejl")
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Introtekst2vejrtraekningerLabel = uilabel(app.UIFigure);
            app.Introtekst2vejrtraekningerLabel.Text = {'Tag en indånding, hvor du trækker '; 'vejret så dybt og hurtigt du kan.'; 'Start når du er klar!'; ''};
            app.AfbrydButton = uibutton(app.UIFigure, 'push');
            app.AfbrydButton.ButtonPushedFcn = createCallbackFcn(app, @AfbrydButtonPushed, true);
            app.AfbrydButton.Text = 'Afbryd';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Vejrtraekning.png';
            app.infoFrame2Button = uibutton(app.UIFigure, 'push');
            app.infoFrame2Button.Tooltip = {'Der er nu registreret 1 gennemført vejrtækning. '; 'Du mangler derfor  1 vejrtrækning mere før din anbefalede træningsmodstand er klar.'; ''; '1. Tag mundstykket op til munden'; '2. Træk vejret ind gennem træningsenheden og pust       derefter ud igen. Du skal trække så meget luft ind som du kan og hurtigst muligt.'; '3. Du vil efterfølgende få visr den anbefalede træningsmodstand'};
            app.infoFrame2Button.Text = 'Info';
            app.VejrtrkningregistreretEditFieldLabel = uilabel(app.UIFigure);
            app.VejrtrkningregistreretEditFieldLabel.Text = 'Vejrtrækning registreret:';
            app.VejrtrkningregistreretEditField = uieditfield(app.UIFigure, 'numeric');
            app.VejrtrkningregistreretEditField.Editable = 'off';
            app.VejrtrkningregistreretEditField.Visible = 'off';
            app.FlotfortstterLabel = uilabel(app.UIFigure);
            app.FlotfortstterLabel.Visible = 'off';
            app.FlotfortstterLabel.Text = 'Flot! - fortsætter...';
            app.skipFrame12Button = uibutton(app.UIFigure, 'push');
            app.skipFrame12Button.ButtonPushedFcn = createCallbackFcn(app, @skipFrame12ButtonPushed, true);
            app.skipFrame12Button.Text = 'Spring modstansindstilling over';
            app.VejrtrkningButton = uibutton(app.UIFigure, 'push');
            app.VejrtrkningButton.ButtonPushedFcn = createCallbackFcn(app, @VejrtrkningButtonPushed, true);
            app.VejrtrkningButton.Text = 'Vejrtrækning';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Frame2_2vejrtraekninger(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end