classdef Start_app_withSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ModelopstningLabel              matlab.ui.control.Label
        LuftvejsmodstandEditFieldLabel  matlab.ui.control.Label
        LuftvejsmodstandEditField       matlab.ui.control.NumericEditField
        ComplianceEditFieldLabel        matlab.ui.control.Label
        ComplianceEditField             matlab.ui.control.NumericEditField
        MIPEditFieldLabel               matlab.ui.control.Label
        MIPEditField                    matlab.ui.control.NumericEditField
        VarighedEditFieldLabel          matlab.ui.control.Label
        VarighedEditField               matlab.ui.control.NumericEditField
        OKButton                        matlab.ui.control.Button
        cmH2OLsLabel                    matlab.ui.control.Label
        LcmH2OLabel                     matlab.ui.control.Label
        cmH2OLabel                      matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        AntalvejtrkningerderskalsimuleresLabel  matlab.ui.control.Label
        AntalvejtrkningerderskalsimuleresSpinner  matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        flow % Simulated flow from model
        pressure% Simulated pressure from model
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            RR = app.LuftvejsmodstandEditField.Value;
            C = app.ComplianceEditField.Value;
            MIP = app.MIPEditField.Value;
            duration = app.VarighedEditField.Value;
            threshold = 0;
            sF = 1000; %Static
            amount = app.AntalvejtrkningerderskalsimuleresSpinner.Value;
            
            [app.flow, app.pressure] = breathSimulation(RR,C,MIP,duration,threshold, sF);
            
                        
            Frame1_Hovedmenu(app, app.flow, app.pressure, RR,C,MIP,duration,threshold,sF,amount);
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ModelopstningLabel = uilabel(app.UIFigure);
            app.ModelopstningLabel.Text = 'Model opsætning';
            app.LuftvejsmodstandEditFieldLabel = uilabel(app.UIFigure);
            app.LuftvejsmodstandEditFieldLabel.Text = 'Luftvejsmodstand';
            app.LuftvejsmodstandEditField = uieditfield(app.UIFigure, 'numeric');
            app.LuftvejsmodstandEditField.Value = 2.5;
            app.ComplianceEditFieldLabel = uilabel(app.UIFigure);
            app.ComplianceEditFieldLabel.Text = 'Compliance';
            app.ComplianceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ComplianceEditField.Value = 0.12;
            app.MIPEditFieldLabel = uilabel(app.UIFigure);
            app.MIPEditFieldLabel.Text = 'MIP';
            app.MIPEditField = uieditfield(app.UIFigure, 'numeric');
            app.MIPEditField.Limits = [0 Inf];
            app.MIPEditField.Value = 40;
            app.VarighedEditFieldLabel = uilabel(app.UIFigure);
            app.VarighedEditFieldLabel.Text = 'Varighed';
            app.VarighedEditField = uieditfield(app.UIFigure, 'numeric');
            app.VarighedEditField.Value = 5;
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.cmH2OLsLabel = uilabel(app.UIFigure);
            app.cmH2OLsLabel.Text = 'cmH2O/L/s';
            app.LcmH2OLabel = uilabel(app.UIFigure);
            app.LcmH2OLabel.Text = 'L/cmH2O';
            app.cmH2OLabel = uilabel(app.UIFigure);
            app.cmH2OLabel.Text = 'cmH2O';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.AntalvejtrkningerderskalsimuleresLabel = uilabel(app.UIFigure);
            app.AntalvejtrkningerderskalsimuleresLabel.Text = 'Antal vejtrækninger der skal simuleres';
            app.AntalvejtrkningerderskalsimuleresSpinner = uispinner(app.UIFigure);
            app.AntalvejtrkningerderskalsimuleresSpinner.Limits = [0 30];
            app.AntalvejtrkningerderskalsimuleresSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Start_app_withSimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end