classdef frame6_modstandsindstilling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FlotLabel                 matlab.ui.control.Label
        NiveauNumberField         matlab.ui.control.NumericEditField
        UndertitelLabel           matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        ModstandindstilletButton  matlab.ui.control.Button
        AfbrydFrame12Button       matlab.ui.control.Button
        infoFrame12Button         matlab.ui.control.Button
        AktuelindstilletmodstandEditFieldLabel  matlab.ui.control.Label
        AktuelindstilletmodstandEditField  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        flows;
        pressures;
        initFlow;
        initPressure;
        
        
        callingApp % inputvindue
        mainMenuApp
        RR;
        C;
        MIP; %The maximum inspiratory pressure generated by patient/model
        duration;
        threshold; %The actual threshold in CmH2O
        sF;
        amount;
        modstand; %The setting/step to set the IMT device to
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, mainMenuApp, initFlow, initPressure, modstand, RR, C, MIP, duration, threshold, sF, amount)
            delete(callingApp);
            app.mainMenuApp = mainMenuApp;
            
            app.RR = RR;
            app.C = C;
            app.MIP = MIP;
            app.duration=duration;
            app.threshold=threshold;
            app.sF=sF;
            app.amount = amount;
            app.modstand = modstand;
            
            app.initFlow = initFlow;
            app.initPressure = initPressure;
                
            app.NiveauNumberField.Value = app.modstand;%Beregnet modstand fra forrige vindue
        end

        % Button pushed function: ModstandindstilletButton
        function ModstandindstilletButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
           
            try
                Frame12_Traening_med_livefeedback(app, app.mainMenuApp, app.initFlow,app.initPressure, app.RR,app.C,app.MIP,app.duration,app.threshold, app.sF, app.amount, app.modstand);
            catch 
                app.UIFigure.Visible = 'on';
                msgbox("En fejl opstod under træningen","Fejl - Modstandsindstilling")
            end


        end

        % Button pushed function: AfbrydFrame12Button
        function AfbrydFrame12ButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FlotLabel = uilabel(app.UIFigure);
            app.FlotLabel.Text = 'Flot!';
            app.NiveauNumberField = uieditfield(app.UIFigure, 'numeric');
            app.NiveauNumberField.Editable = 'off';
            app.UndertitelLabel = uilabel(app.UIFigure);
            app.UndertitelLabel.Text = {'Dine to vejrtrækninger er registreret.'; 'Indstil nu modstanden på din træningsenhed til:'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'IMT_Trainer.png';
            app.ModstandindstilletButton = uibutton(app.UIFigure, 'push');
            app.ModstandindstilletButton.ButtonPushedFcn = createCallbackFcn(app, @ModstandindstilletButtonPushed, true);
            app.ModstandindstilletButton.Text = 'Modstand indstillet';
            app.AfbrydFrame12Button = uibutton(app.UIFigure, 'push');
            app.AfbrydFrame12Button.ButtonPushedFcn = createCallbackFcn(app, @AfbrydFrame12ButtonPushed, true);
            app.AfbrydFrame12Button.Text = 'Afbryd';
            app.infoFrame12Button = uibutton(app.UIFigure, 'push');
            app.infoFrame12Button.Tooltip = {'Du skal nu indstille modstanden på din træningsenhed, '; 'så du er klar til at påbegynde træningen.'; 'Du indstiller modstanden ved at dreje hjulet med tal på. '; 'Modtanden på trænignsenheden er indstillet når tallet ud'; 'fra pillen svarer til den anbefalede træningsmodstand.'; 'Når du har indstillet modstanden trykker du på den grøn-'; 'ne knap “modstand indstillet”'};
            app.infoFrame12Button.Text = 'Info';
            app.AktuelindstilletmodstandEditFieldLabel = uilabel(app.UIFigure);
            app.AktuelindstilletmodstandEditFieldLabel.Text = 'Aktuel indstillet modstand';
            app.AktuelindstilletmodstandEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = frame6_modstandsindstilling(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end